classdef DALo96 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RadiusLocalizationPredecesorsButtonGroup  matlab.ui.container.ButtonGroup
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        DataassimilationinteractiveplatformPanel  matlab.ui.container.Panel
        SimulationtimeEditField       matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel  matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        ForcingSlider                 matlab.ui.control.Slider
        ForcingSliderLabel            matlab.ui.control.Label
        NumberofensemblesSlider       matlab.ui.control.Slider
        NumberofensemblesSliderLabel  matlab.ui.control.Label
        Lorenz96Label                 matlab.ui.control.Label
        NumberofstatesEditField       matlab.ui.control.NumericEditField
        NumberofstatesEditFieldLabel  matlab.ui.control.Label
        StatetovisualizeSpinner       matlab.ui.control.Spinner
        StatetovisualizeSpinnerLabel  matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Panel_2                       matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        Switch                        matlab.ui.control.ToggleSwitch
        DAconfigurationsPanel         matlab.ui.container.Panel
        ObservationfrequencySpinner   matlab.ui.control.Spinner
        ObservationfrequencySpinnerLabel  matlab.ui.control.Label
        SelecttimestepSpinner         matlab.ui.control.Spinner
        SelecttimestepSpinnerLabel    matlab.ui.control.Label
        DAtechniqueDropDown           matlab.ui.control.DropDown
        DAtechniqueDropDownLabel      matlab.ui.control.Label
        DisturbparameterKnob          matlab.ui.control.Knob
        DisturbparameterKnobLabel     matlab.ui.control.Label
        EnsembleInflationKnob         matlab.ui.control.Knob
        EnsembleInflationKnobLabel    matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Panel_5                       matlab.ui.container.Panel
        ErrorLabel                    matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        TruthstateLabel               matlab.ui.control.Label
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ScoresPanel                   matlab.ui.container.Panel
        Lamp2                         matlab.ui.control.Lamp
        Switch_2                      matlab.ui.control.Switch
        MFBGauge                      matlab.ui.control.Gauge
        MFBGaugeLabel                 matlab.ui.control.Label
        RMSEGauge                     matlab.ui.control.SemicircularGauge
        RMSEGaugeLabel                matlab.ui.control.Label
        CORRGauge                     matlab.ui.control.LinearGauge
        CORRGaugeLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        AllstatesintimeLabel          matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NumberofensemblesSliderValueChanged(app, event)
            value_ensembles = app.NumberofensemblesSlider.Value;
           
        end

        % Callback function
        function NumberofstatesEditFieldValueChanged(app, event)
            value_states = app.NumberofstatesEditField.Value;
            
        end

        % Value changed function: NumberofstatesEditField
        function NumberofstatesEditFieldValueChanged2(app, event)
            value = app.NumberofstatesEditField.Value;
            x0=randn(value,1);
         app.NumberofstatesEditField.UserData.x0=x0;
        end

        % Value changed function: NumberofensemblesSlider, 
        % SimulationtimeEditField
        function SimulationtimeEditFieldValueChanged(app, event)
            value = app.SimulationtimeEditField.Value;


        end

        % Value changing function: NumberofensemblesSlider
        function NumberofensemblesSliderValueChanging(app, event)
            changingValue = event.Value;

cla(app.UIAxes) 
cla(app.UIAxes2)  

            % determine which discrete option the current value is closest to.
    [~, minIdx] = min(abs( changingValue  - event.Source.MajorTicks(:)));
    % move the slider to that option
    event.Source.Value = event.Source.MajorTicks(minIdx);
    % Override the selected value if you plan on using it within this function
     changingValue  = event.Source.MajorTicks(minIdx);


num=changingValue ;         % Num ensemble
app.SimulationtimeEditField.UserData.changingValue=changingValue;

rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length
m=40;            % Number of observations
n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 


sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;

incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=app.DisturbparameterKnob.Value;     
    Dist=fact*randn(num,1);
    FF=app.ForcingSlider.Value;
    for i=1:num
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
        % a=plot(Xreal(30,:),'g');
        guardar(i,:)=Xreal(ste_inter,:);
        guardar2(i,:,:)=Xreal(:,:);
        hold on
       
    end
app.NumberofensemblesSlider.UserData.guardar=guardar;
app.NumberofensemblesSlider.UserData.Xreal=Xreal;

ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;

x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];
fill(app.UIAxes,x2, inBetween, 'g');
hold(app.UIAxes,"on");
plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
plot(app.UIAxes,mean(guardar),'k');
title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
grid on
xline(app.UIAxes,app.SelecttimestepSpinner.Value)
xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[0 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)

imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[0 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Callback function
        function ForcingFactorEditFieldValueChanged(app, event)


        end

        % Value changed function: DisturbparameterKnob
        function DisturbparameterKnobValueChanged(app, event)
            value = app.DisturbparameterKnob.Value;
            
cla(app.UIAxes)  
cla(app.UIAxes2)  

  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length
m=40;            % Number of observations
n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;
incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=changingValueknob;     
    Dist=fact*randn(num,1);
    FF=app.ForcingSlider.Value;
    for i=1:num
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
        % a=plot(Xreal(30,:),'g');
        guardar(i,:)=Xreal(ste_inter,:);
        guardar2(i,:,:)=Xreal(:,:);
        hold on
       
    end

%    guardar=app.NumberofensemblesSlider.UserData.guardar;
%         Xreal=app.NumberofensemblesSlider.UserData.Xreal;

    ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


fill(app.UIAxes,x2, inBetween, 'g');
 hold(app.UIAxes,"on");
 plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
 plot(app.UIAxes,mean(guardar),'k');
 title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
 grid on
 xline(app.UIAxes,app.SelecttimestepSpinner.Value)
 xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[0 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)

imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[0 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)


        end

        % Value changed function: StatetovisualizeSpinner
        function StatetovisualizeSpinnerValueChanged(app, event)
            ste_inter = app.StatetovisualizeSpinner.Value;


cla(app.UIAxes)  
cla(app.UIAxes2)  

  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length
m=40;            % Number of observations
n=app.NumberofstatesEditField.Value;            % State number

%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;
incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=changingValueknob;     
    Dist=fact*randn(num,1);
    FF=app.ForcingSlider.Value;
    for i=1:num
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
        % a=plot(Xreal(30,:),'g');
        guardar(i,:)=Xreal(ste_inter,:);
        guardar2(i,:,:)=Xreal(:,:);
        hold on
       
    end

%    guardar=app.NumberofensemblesSlider.UserData.guardar;
%         Xreal=app.NumberofensemblesSlider.UserData.Xreal;

    ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


fill(app.UIAxes,x2, inBetween, 'g');
 hold(app.UIAxes,"on");
 plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
 plot(app.UIAxes,mean(guardar),'k');
 title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
 grid on
 xline(app.UIAxes,app.SelecttimestepSpinner.Value)
 xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[0 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)


imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[0 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)


        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: ForcingSlider
        function ForcingSliderValueChanged(app, event)
            value = app.ForcingSlider.Value;


cla(app.UIAxes)  
cla(app.UIAxes2)  

  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length
m=40;            % Number of observations
n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;
incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=changingValueknob;     
    Dist=fact*randn(num,1);
     FF=app.ForcingSlider.Value;
     for i=1:num
         FF=FF+Dist(randi([1 num]),1);
         [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
         % a=plot(Xreal(30,:),'g');
         guardar(i,:)=Xreal(ste_inter,:);
         guardar2(i,:,:)=Xreal(:,:);
         hold on
        
     end

%    guardar=app.NumberofensemblesSlider.UserData.guardar;
%         Xreal=app.NumberofensemblesSlider.UserData.Xreal;

    ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


fill(app.UIAxes,x2, inBetween, 'g');
 hold(app.UIAxes,"on");
 plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
 plot(app.UIAxes,mean(guardar),'k');
 title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
 grid on
 xline(app.UIAxes,app.SelecttimestepSpinner.Value)
 xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[0 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)


imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[0 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)


        end

        % Callback function
        function SwitchValueChanged(app, event)
                       value = app.SwitchValueChanged.Value
     app.Lamp.Color = [1,0,0] ; 
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value
            if strcmp(value,'On')
            app.Lamp.Color = 'g'; 
            app.RMSEGauge.Value = 15;
            app.RMSEGauge.Value = calc_RMSE(app.DAtechniqueDropDown.UserData.V1,app.DAtechniqueDropDown.UserData.V2);
            
            else
            app.Lamp.Color = 'r'; 
            cla(app.UIAxes)  
            cla(app.UIAxes2) 
            cla(app.UIAxes3)  
            cla(app.UIAxes4) 
            cla(app.UIAxes5) 
            app.RMSEGauge.Value=0
            end
      
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;


            if strcmp(value,'On')
            app.Lamp2.Color = 'g'; 
            else
            app.Lamp2.Color = 'r'; 
            end
            
        end

        % Value changed function: EnsembleInflationKnob
        function EnsembleInflationKnobValueChanged(app, event)
           sparse = app.EnsembleInflationKnob.Value;

            
cla(app.UIAxes)  
cla(app.UIAxes2)  

  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length
m=40;            % Number of observations
n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 


x0=sparse*app.NumberofstatesEditField.UserData.x0;
incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=changingValueknob;     
    Dist=fact*randn(num,1);
    FF=app.ForcingSlider.Value;
    for i=1:num
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
        % a=plot(Xreal(30,:),'g');
        guardar(i,:)=Xreal(ste_inter,:);
        guardar2(i,:,:)=Xreal(:,:);
        hold on
       
    end

%    guardar=app.NumberofensemblesSlider.UserData.guardar;
%         Xreal=app.NumberofensemblesSlider.UserData.Xreal;

    ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


fill(app.UIAxes,x2, inBetween, 'g');
 hold(app.UIAxes,"on");
 plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
 plot(app.UIAxes,mean(guardar),'k');
 title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
 grid on
 xline(app.UIAxes,app.SelecttimestepSpinner.Value)
 xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[0 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)


imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[0 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)


        end

        % Value changed function: SelecttimestepSpinner
        function SelecttimestepSpinnerValueChanged(app, event)
            value = app.SelecttimestepSpinner.Value;
           
          
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Value changed function: DAtechniqueDropDown
        function DAtechniqueDropDownValueChanged(app, event)
            value = app.DAtechniqueDropDown.Value;
            switch app.DAtechniqueDropDown.Value
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
                case 'EnKF'

             
          cla(app.UIAxes5) 


  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble

Tsim=app.SimulationtimeEditField.Value;        % Simulation time

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;

n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 

dt=0.01;         % Step length
m=40;            % Number of observations

%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=10; % Frequency of observations
pfactorEnkf = 1.2;  %INFLATION FACTOR

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

FF=7;
guardar=zeros(num,Tsim+M*frequency);
fact=0.1;

       
    Dist=fact*randn(num,1);
    
   
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector


        %% %% == Data assimilation part ==
% 
 Xb=zeros(n,num,Tsim);       % Background ensemble initialization
% X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
% X_S=zeros(n,num,Tsim);
% 
 meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
 Xb(:,:,1)=0.5*randn(n,num);      % Initial condition creation
% 
 Xa=Xb;

 meanxa_EnKF=zeros(n,Tsim);              % initialization of the vector to put the analisis ENKF mean
 B=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for EnKF

 muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
%  
 Y=H*Xreal;     %  Sampling of the observation operator


for i=2:Tsim-1
%======== Forecast Step==========

% foplot(movmean(Error_ENKF,window));hold on
% plot(movmean(Error_ENKF_schur,window));hold on
% plot(movmean(Error_ENKF_MC,window));hold on
% plot(movmean(Error_ENKS,window));hold on
% plot(movmean(Error_ENKS_chol,window));hold on
  for en=1:num
      % FF=7;
      fact=0.1;
      Dist=fact*randn(num,1);
      FF=FF+Dist(randi([1 num]),1);
     [Xb(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(Xb(:,en,i-1)),FF);
    end
  
     meanxb(:,i)=mean(Xb(:,:,i),2);    
     
if sum(muestreo==i)   %Validation if there are a observation available
     
%   for Nen=1:N
%      XXb(:,Nen,i)= Xb(:,Nen,i)-meanxb(:,i);  
%   end
%   
      Xbp(:,:,i)=meanxb(:,i)*ones(1,num)+ pfactorEnkf*(Xb(:,:,i)-meanxb(:,i)*ones(1,num));
      meanXbp(:,i) = mean(Xbp(:,:,i),2);   
      XXb(:,:,i) =Xbp(:,:,i)-meanXbp(:,i)*ones(1,num);
% Standard EnKF Background matrix 

   Binv=pinv((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));
    BCov(i,:,:)=Binv;

  % [L,D, Dsquare]=Calculo_B_CholeskyInc(XXb,r);
  % Binv = L'*D*L;
  
   %%
%======================== Analysis Step ENKF=========================================
muestra=0;     
%K=B*H'*pinv(H*B*H'+R);     % Matriz de Ganancia de Kalman
 
  K2 = (Binv+ H'*(R\H));
    for en=1:num
         
         C(:,i)= Y(:,i)+sigma*randn(m,1); %  Almacenamiento de los datos sint??ticos
          D= (C(:,i)-H*Xb(:,en,i));   
         Xa(:,en,i)=Xb(:,en,i)+((K2\H')*(R\D));

    end
    Xb(:,:,i)=Xa(:,:,i);
    
    
else
     Xb(:,:,i)=Xb(:,:,i);
end
meanxa_EnKF(:,i)=mean(Xb(:,:,i),2);

end


plot(app.UIAxes5,Xreal(ste_inter,1:i-20),'r','LineWidth',2)
hold(app.UIAxes5,'On')
plot(app.UIAxes5,meanxa_EnKF(ste_inter,1:i-20),'--b','LineWidth',2)
legend(app.UIAxes5,{'X truth','Xa ENKF'})
% hold on

 imagesc(app.UIAxes4,squeeze(BCov(app.SelecttimestepSpinner.Value,:,:)));
 colormap(app.UIAxes4,'jet'); colorbar(app.UIAxes4)
xlim(app.UIAxes4,[1 n])
ylim(app.UIAxes4,[1 n])

for i=1:Tsim
Error_ENKF(i)=norm(abs(sum(meanxa_EnKF(ste_inter,i)-Xreal(ste_inter,i))));hold on
end
V1=squeeze(meanxa_EnKF(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));

app.DAtechniqueDropDown.UserData.V1=V1;
app.DAtechniqueDropDown.UserData.V2=V2;
app.DAtechniqueDropDown.Value=15
plot(app.UIAxes3,movmean(Error_ENKF,10));
legend(app.UIAxes3,{'EnKF'})

%     ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
% x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];
% 
% 
% 
% fill(app.UIAxes,x2, inBetween, 'g');
%  hold(app.UIAxes,"on");
%  plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
%  plot(app.UIAxes,mean(guardar),'k');
%  title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
%  grid on
%  xline(app.UIAxes,app.SelecttimestepSpinner.Value)
%  xlim(app.UIAxes,[0 Tsim])
% 
% imagesc(app.UIAxes2,guardar)
% xlim(app.UIAxes2,[0 Tsim])
% ylim(app.UIAxes2,[0 num])
% colormap(app.UIAxes2,cool)
% colorbar(app.UIAxes2)


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKF Schur'
    
            
            cla(app.UIAxes3)  
            cla(app.UIAxes4) 
            cla(app.UIAxes5)  


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKS'
             
            
            cla(app.UIAxes3)  
            cla(app.UIAxes4) 
            cla(app.UIAxes5)  


          cla(app.UIAxes5) 


  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble

Tsim=app.SimulationtimeEditField.Value;        % Simulation time

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;

n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 

dt=0.01;         % Step length
m=40;            % Number of observations

%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=10; % Frequency of observations
pfactorEnkf = 1.2;  %INFLATION FACTOR
pfactorEnkf = 1.2;  %INFLATION FACTOR

 meanxa_EnKS=zeros(n,Tsim);              % initialization of the vector to put the analisis EnKS mean
BS=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for Smoother
Xb=zeros(n,num,Tsim);       % Background ensemble initialization
% X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
 X_S=zeros(n,num,Tsim);
% 
 meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
 Xb(:,:,1)=0.5*randn(n,num);      % Initial condition creation
% 
 Xa=Xb;
 X_S=Xb;
%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

FF=7;
guardar=zeros(num,Tsim+M*frequency);
fact=0.1;

       
    Dist=fact*randn(num,1);
    
   
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector


muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
%  
 Y=H*Xreal;     %  Sampling of the observation operator

for i=2:Tsim-M*frequency
%======== Forecast Step==========

    for en=1:num
         [X_S(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(Xa(:,en,i-1)),FF);
    end
  
     meanxb(:,i)=mean(X_S(:,:,i),2);    
    
  for Nen=1:num
     XXb(:,Nen,i)= X_S(:,Nen,i)-meanxb(:,i);  
  end
  
%  Standard EnKF Background matrix 

   B(:,:,i)=((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));
     BCov(:,:,i)=B;

   if sum(muestreo==i)   %Validation if there are a observation available
%======================== Analysis Step ENKS=========================================

    X_aw(:,:,1)=X_S(:,:,i);
    
    if M==1
    B_aw(:,:,1)=B(:,:,i);    
    end
        for kk=2:(M*frequency)
            for en=1:num    
                [X_aw(:,en,kk)]=Lorenz_96_one_step(1,dt,squeeze(X_aw(:,en,kk-1)),FF);
            end
            meanxb_aw(:,kk)=mean(X_aw(:,:,kk),2);    
    
             for Nen=1:num
                XXb_aw(:,Nen,kk)= X_aw(:,Nen,kk)-meanxb_aw(:,kk)+1*randn(n,1);  
             end
  
             B_aw(:,:,kk)=((1/(N-1))*(XXb_aw(:,:,kk)*XXb_aw(:,:,kk)'));   
        end
    aux=0;
    for kk2=1:frequency:M*frequency
        K(:,:,kk2)=B_aw(:,:,kk2)*H'*pinv(H*B_aw(:,:,kk2)*H'+R);     % Matriz de Ganancia de Kalman
        C(:,kk2)= Y(:,i+kk2-1)+sigma*randn(m,1);
        aux=aux+(K(:,:,kk2)*(C(:,kk2)*ones(1,40)-H*X_aw(:,:,kk2)))/M;
    end
    
    for en=1:num
      Xa(:,en,i)=X_S(:,en,i)+aux(:,en);
    end
    

else 
  Xa(:,:,i)=X_S(:,:,i);  
end
  meanxa_EnKS(:,i)=mean(Xa(:,:,i),2);                                                                                                                                                                                                                                                                                                                                              
end


plot(app.UIAxes5,Xreal(ste_inter,1:i-20),'r','LineWidth',2)
hold(app.UIAxes5,'On')
plot(app.UIAxes5,meanxa_EnKS(ste_inter,1:i-20),'--b','LineWidth',2)
legend(app.UIAxes5,{'X truth','Xa ENKF'})
% hold on

 imagesc(app.UIAxes4,squeeze(BCov(:,:,app.SelecttimestepSpinner.Value)));
 colormap(app.UIAxes4,'jet'); colorbar(app.UIAxes4)
xlim(app.UIAxes4,[1 n])
ylim(app.UIAxes4,[1 n])

for i=1:Tsim
Error_ENKS(i)=norm(abs(sum(meanxa_EnKS(ste_inter,i)-Xreal(ste_inter,i))));hold on
end
V1=squeeze(meanxa_EnKS(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));

 app.RMSEGauge.Value = 15;
plot(app.UIAxes3,movmean(Error_ENKS,10));
legend(app.UIAxes3,{'EnKF'})


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKS MC'
             
           
            cla(app.UIAxes3)  
            cla(app.UIAxes4) 
            cla(app.UIAxes5)  
            
            end


        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            
        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)
            
        end

        % Size changed function: ScoresPanel
        function ScoresPanelSizeChanged(app, event)
            position = app.ScoresPanel.Position;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {788, 788};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {262, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DAconfigurationsPanel = uipanel(app.LeftPanel);
            app.DAconfigurationsPanel.Title = 'DA configurations';
            app.EnsembleInflationKnobLabel = uilabel(app.DAconfigurationsPanel);
            app.EnsembleInflationKnobLabel.Text = 'Ensemble Inflation';
            app.EnsembleInflationKnob = uiknob(app.DAconfigurationsPanel, 'continuous');
            app.EnsembleInflationKnob.Limits = [0.01 5];
            app.EnsembleInflationKnob.ValueChangedFcn = createCallbackFcn(app, @EnsembleInflationKnobValueChanged, true);
            app.EnsembleInflationKnob.Value = 0.01;
            app.DisturbparameterKnobLabel = uilabel(app.DAconfigurationsPanel);
            app.DisturbparameterKnobLabel.Text = 'Disturb parameter ';
            app.DisturbparameterKnob = uiknob(app.DAconfigurationsPanel, 'continuous');
            app.DisturbparameterKnob.Limits = [0 2];
            app.DisturbparameterKnob.ValueChangedFcn = createCallbackFcn(app, @DisturbparameterKnobValueChanged, true);
            app.DAtechniqueDropDownLabel = uilabel(app.DAconfigurationsPanel);
            app.DAtechniqueDropDownLabel.Text = 'DA technique';
            app.DAtechniqueDropDown = uidropdown(app.DAconfigurationsPanel);
            app.DAtechniqueDropDown.Items = {'None', 'EnKF', 'EnKF Schur', 'EnKS', 'EnKS MC'};
            app.DAtechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @DAtechniqueDropDownValueChanged, true);
            app.DAtechniqueDropDown.Value = 'None';
            app.SelecttimestepSpinnerLabel = uilabel(app.DAconfigurationsPanel);
            app.SelecttimestepSpinnerLabel.Text = 'Select timestep';
            app.SelecttimestepSpinner = uispinner(app.DAconfigurationsPanel);
            app.SelecttimestepSpinner.Limits = [1 Inf];
            app.SelecttimestepSpinner.ValueChangedFcn = createCallbackFcn(app, @SelecttimestepSpinnerValueChanged, true);
            app.SelecttimestepSpinner.Value = 1;
            app.ObservationfrequencySpinnerLabel = uilabel(app.DAconfigurationsPanel);
            app.ObservationfrequencySpinnerLabel.Text = 'Observation frequency';
            app.ObservationfrequencySpinner = uispinner(app.DAconfigurationsPanel);
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Switch = uiswitch(app.Panel_2, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Lamp = uilamp(app.Panel_2);
            app.DataassimilationinteractiveplatformPanel = uipanel(app.LeftPanel);
            app.DataassimilationinteractiveplatformPanel.Title = 'Data assimilation interactive platform';
            app.Image2 = uiimage(app.DataassimilationinteractiveplatformPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'Lorenz96.svg';
            app.StatetovisualizeSpinnerLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.StatetovisualizeSpinnerLabel.Text = 'State to visualize';
            app.StatetovisualizeSpinner = uispinner(app.DataassimilationinteractiveplatformPanel);
            app.StatetovisualizeSpinner.Limits = [1 Inf];
            app.StatetovisualizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StatetovisualizeSpinnerValueChanged, true);
            app.StatetovisualizeSpinner.Value = 1;
            app.NumberofstatesEditFieldLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.NumberofstatesEditFieldLabel.Text = 'Number of states';
            app.NumberofstatesEditField = uieditfield(app.DataassimilationinteractiveplatformPanel, 'numeric');
            app.NumberofstatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstatesEditFieldValueChanged2, true);
            app.Lorenz96Label = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.Lorenz96Label.Text = 'Lorenz 96  ';
            app.NumberofensemblesSliderLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.NumberofensemblesSliderLabel.Text = 'Number of ensembles';
            app.NumberofensemblesSlider = uislider(app.DataassimilationinteractiveplatformPanel);
            app.NumberofensemblesSlider.Limits = [3 60];
            app.NumberofensemblesSlider.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.NumberofensemblesSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofensemblesSliderValueChanging, true);
            app.NumberofensemblesSlider.MinorTicks = [];
            app.NumberofensemblesSlider.Value = 3;
            app.ForcingSliderLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.ForcingSliderLabel.Text = 'Forcing';
            app.ForcingSlider = uislider(app.DataassimilationinteractiveplatformPanel);
            app.ForcingSlider.Limits = [6 12];
            app.ForcingSlider.ValueChangedFcn = createCallbackFcn(app, @ForcingSliderValueChanged, true);
            app.ForcingSlider.Value = 6;
            app.Image3 = uiimage(app.DataassimilationinteractiveplatformPanel);
            app.Image3.ImageSource = 'equation.svg';
            app.SimulationtimeEditFieldLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.DataassimilationinteractiveplatformPanel, 'numeric');
            app.SimulationtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.RadiusLocalizationPredecesorsButtonGroup = uibuttongroup(app.LeftPanel);
            app.RadiusLocalizationPredecesorsButtonGroup.Title = 'Radius Localization/Predecesors';
            app.Button = uiradiobutton(app.RadiusLocalizationPredecesorsButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.RadiusLocalizationPredecesorsButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.RadiusLocalizationPredecesorsButtonGroup);
            app.Button_3.Text = '2';
            app.RightPanel = uipanel(app.GridLayout);
            app.AllstatesintimeLabel = uilabel(app.RightPanel);
            app.AllstatesintimeLabel.Text = 'All states in time';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'UniversitiesLogo.png';
            app.ScoresPanel = uipanel(app.RightPanel);
            app.ScoresPanel.Title = 'Scores';
            app.ScoresPanel.SizeChangedFcn = createCallbackFcn(app, @ScoresPanelSizeChanged, true);
            app.CORRGaugeLabel = uilabel(app.ScoresPanel);
            app.CORRGaugeLabel.Text = 'CORR';
            app.CORRGauge = uigauge(app.ScoresPanel, 'linear');
            app.CORRGauge.Limits = [0 1];
            app.RMSEGaugeLabel = uilabel(app.ScoresPanel);
            app.RMSEGaugeLabel.Text = 'RMSE';
            app.RMSEGauge = uigauge(app.ScoresPanel, 'semicircular');
            app.MFBGaugeLabel = uilabel(app.ScoresPanel);
            app.MFBGaugeLabel.Text = 'MFB';
            app.MFBGauge = uigauge(app.ScoresPanel, 'circular');
            app.MFBGauge.Limits = [-10 10];
            app.Switch_2 = uiswitch(app.ScoresPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Lamp2 = uilamp(app.ScoresPanel);
            app.Panel_3 = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 'Simulation time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes2, 'Simulation time')
            ylabel(app.UIAxes2, 'Ensemble member')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes6 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes6, 'Simulation time')
            ylabel(app.UIAxes6, 'State')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.TruthstateLabel = uilabel(app.Panel_3);
            app.TruthstateLabel.Text = 'Truth state';
            app.Panel_4 = uipanel(app.RightPanel);
            app.UIAxes5 = uiaxes(app.Panel_4);
            xlabel(app.UIAxes5, 'Simulation time')
            ylabel(app.UIAxes5, 'State')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.UIAxes4 = uiaxes(app.Panel_4);
            title(app.UIAxes4, 'Covariance Background Matrix')
            xlabel(app.UIAxes4, 'State')
            ylabel(app.UIAxes4, 'State')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.Panel_5 = uipanel(app.RightPanel);
            app.UIAxes3 = uiaxes(app.Panel_5);
            xlabel(app.UIAxes3, 'Simulation time')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YScale = 'log';
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.ErrorLabel = uilabel(app.Panel_5);
            app.ErrorLabel.Text = 'Error ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DALo96

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end