classdef DALo96_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RadiusLocalizationButtonGroup  matlab.ui.container.ButtonGroup
        Radio                          matlab.ui.control.NumericEditField
        RadioLabel                     matlab.ui.control.Label
        Radio3Button                   matlab.ui.control.RadioButton
        Radio2Button                   matlab.ui.control.RadioButton
        Radio1Button                   matlab.ui.control.RadioButton
        DataassimilationinteractiveplatformPanel  matlab.ui.container.Panel
        Image5                         matlab.ui.control.Image
        SimulationtimeEditField        matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel   matlab.ui.control.Label
        ForcingSlider                  matlab.ui.control.Slider
        ForcingSliderLabel             matlab.ui.control.Label
        NumberofensemblesSlider        matlab.ui.control.Slider
        NumberofensemblesSliderLabel   matlab.ui.control.Label
        Lorenz96Label                  matlab.ui.control.Label
        NumberofstatesEditField        matlab.ui.control.NumericEditField
        NumberofstatesEditFieldLabel   matlab.ui.control.Label
        StatetovisualizeSpinner        matlab.ui.control.Spinner
        StatetovisualizeSpinnerLabel   matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Panel_2                        matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        Switch                         matlab.ui.control.ToggleSwitch
        DAconfigurationsPanel          matlab.ui.container.Panel
        AButton                        matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        ObservationfrequencySpinner    matlab.ui.control.Spinner
        ObservationfrequencySpinnerLabel  matlab.ui.control.Label
        SelecttimestepSpinner          matlab.ui.control.Spinner
        SelecttimestepSpinnerLabel     matlab.ui.control.Label
        DAtechniqueDropDown            matlab.ui.control.DropDown
        DAtechniqueDropDownLabel       matlab.ui.control.Label
        DisturbparameterKnob           matlab.ui.control.Knob
        DisturbparameterKnobLabel      matlab.ui.control.Label
        EnsembleInflationKnob          matlab.ui.control.Knob
        EnsembleInflationKnobLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Panel_5                        matlab.ui.container.Panel
        TimeserieserrorLabel           matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EnKFTab                        matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        EnKFSchurTab                   matlab.ui.container.Tab
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        EnKFMCTab_2                    matlab.ui.container.Tab
        UIAxes10                       matlab.ui.control.UIAxes
        UIAxes9                        matlab.ui.control.UIAxes
        EnKSTab                        matlab.ui.container.Tab
        UIAxes12                       matlab.ui.control.UIAxes
        UIAxes11                       matlab.ui.control.UIAxes
        EnKSMCTab                      matlab.ui.container.Tab
        UIAxes14                       matlab.ui.control.UIAxes
        UIAxes13                       matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        TruthstateLabel                matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ScoresPanel                    matlab.ui.container.Panel
        FractionalBiasEditField        matlab.ui.control.NumericEditField
        FractionalBiasEditFieldLabel   matlab.ui.control.Label
        RootMeanSquareEditField        matlab.ui.control.NumericEditField
        RootMeanSquareEditFieldLabel   matlab.ui.control.Label
        CorrelationEditField           matlab.ui.control.NumericEditField
        CorrelationEditFieldLabel      matlab.ui.control.Label
        Lamp2                          matlab.ui.control.Lamp
        Switch_2                       matlab.ui.control.Switch
        MFBGauge                       matlab.ui.control.Gauge
        MFBGaugeLabel                  matlab.ui.control.Label
        RMSEGauge                      matlab.ui.control.SemicircularGauge
        RMSEGaugeLabel                 matlab.ui.control.Label
        CORRGauge                      matlab.ui.control.LinearGauge
        CORRGaugeLabel                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        AllstatesintimeLabel           matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NumberofensemblesSliderValueChanged(app, event)
            value_ensembles = app.NumberofensemblesSlider.Value;
           
        end

        % Callback function
        function NumberofstatesEditFieldValueChanged(app, event)
            value_states = app.NumberofstatesEditField.Value;
            
        end

        % Value changed function: NumberofstatesEditField
        function NumberofstatesEditFieldValueChanged2(app, event)
            value = app.NumberofstatesEditField.Value;
            x0=randn(value,1);
         app.NumberofstatesEditField.UserData.x0=x0;
        end

        % Value changed function: NumberofensemblesSlider, 
        % SimulationtimeEditField
        function SimulationtimeEditFieldValueChanged(app, event)
            value = app.SimulationtimeEditField.Value;


        end

        % Value changing function: NumberofensemblesSlider
        function NumberofensemblesSliderValueChanging(app, event)
           
            if strcmp(app.Switch.Value,'On')    
            
            changingValue = event.Value;

           cla(app.UIAxes) 
           cla(app.UIAxes2)  

          % determine which discrete option the current value is closest to.
        [~, minIdx] = min(abs( changingValue  - event.Source.MajorTicks(:)));
        % move the slider to that option
        event.Source.Value = event.Source.MajorTicks(minIdx);
        % Override the selected value if you plan on using it within this function
         changingValue  = event.Source.MajorTicks(minIdx);


        num=changingValue ;         % Num ensemble
        app.SimulationtimeEditField.UserData.changingValue=changingValue;
        
        
        Tsim=app.SimulationtimeEditField.Value;        % Simulation time
        dt=0.01;         % Step length
        
        n=app.NumberofstatesEditField.Value;            % State number
        m=n;            % Number of observations
        ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
        %F=8;             % Forcing factor model  In this version of the code the
        %Forcing parameter is perturbed
        r=3;             % Predecesors radio
        sigma=1e-2;        % Noise factor for the error covariance matrix terms
        R=sigma^2*eye(m);  % Observation Covariance matrix 
        H = eye(n,n);      % Observation operator matrix
        H = H(randperm(n,m),:);
        M=2; %Ventana de asimilacion
        frequency=1; % Frequency of observations  
        
        %% ==============Generate real state=================
        % This part generates the ensemble perturbing the forcing parameter 
        
        
        sparse=app.EnsembleInflationKnob.Value;
        
        x0=sparse*app.NumberofstatesEditField.UserData.x0;
        
        incr=2;
        
        guardar=zeros(num,Tsim+M*frequency);
        
        fact=app.DisturbparameterKnob.Value;     
        
        randVar=randn(num,1);
        app.NumberofensemblesSlider.UserData.randVar=randVar;   % save the random perturbation of the nuber of ensembles
        Dist=fact*randVar;

        FF=app.ForcingSlider.Value;
        for i=1:num
            FF=FF+Dist(randi([1 num]),1);
            [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
            % a=plot(Xreal(30,:),'g');
            guardar(i,:)=Xreal(ste_inter,:);
            guardar2(i,:,:)=Xreal(:,:);
          
           
        end
        app.NumberofensemblesSlider.UserData.guardar=guardar;
        app.NumberofensemblesSlider.UserData.Xreal=Xreal;
        
        ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
        
        x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];
        fill(app.UIAxes,x2, inBetween, 'g');
        hold(app.UIAxes,"on");
        plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
        plot(app.UIAxes,mean(guardar),'k');
        title(app.UIAxes,sprintf('Forcing factor=%.3f state=%.0f',app.ForcingSlider.Value,app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        xline(app.UIAxes,app.SelecttimestepSpinner.Value)
        xlim(app.UIAxes,[0 Tsim])
        ylim(app.UIAxes,[-15 15])
        
        imagesc(app.UIAxes2,guardar)
        title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        xlim(app.UIAxes2,[0 Tsim])
        ylim(app.UIAxes2,[1 num])
        colormap(app.UIAxes2,cool)
        colorbar(app.UIAxes2)
        
        imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
        title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        xlim(app.UIAxes6,[0 Tsim])
        ylim(app.UIAxes6,[1 n])
        colormap(app.UIAxes6,cool)
        colorbar(app.UIAxes6)
           end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Callback function
        function ForcingFactorEditFieldValueChanged(app, event)


        end

        % Value changed function: DisturbparameterKnob
        function DisturbparameterKnobValueChanged(app, event)
             if strcmp(app.Switch.Value,'On')
            value = app.DisturbparameterKnob.Value;
            
        cla(app.UIAxes)  
        cla(app.UIAxes2)  
        
          changingValueknob = app.DisturbparameterKnob.Value;


        num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
        
        Tsim=app.SimulationtimeEditField.Value;        % Simulation time
        dt=0.01;         % Step length
        
        n=app.NumberofstatesEditField.Value;            % State number
        m=n;            % Number of observations
        ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
        %F=8;             % Forcing factor model  In this version of the code the
        %Forcing parameter is perturbed
        r=3;             % Predecesors radio
        sigma=1e-2;        % Noise factor for the error covariance matrix terms
        R=sigma^2*eye(m);  % Observation Covariance matrix 
        H = eye(n,n);      % Observation operator matrix
        H = H(randperm(n,m),:);
        M=2; %Ventana de asimilacion
        frequency=1; % Frequency of observations  
        
        %% ==============Generate real state=================
        % This part generates the ensemble perturbing the forcing parameter 
        
        sparse=app.EnsembleInflationKnob.Value;
        
        x0=sparse*app.NumberofstatesEditField.UserData.x0;
        incr=2;
        
        guardar=zeros(num,Tsim+M*frequency);
        
        fact=changingValueknob;     
         randVar=app.NumberofensemblesSlider.UserData.randVar;     
        Dist=fact*randVar;
            FF=app.ForcingSlider.Value;
            for i=1:num
                FF=FF+Dist(randi([1 num]),1);
                [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
                % a=plot(Xreal(30,:),'g');
                guardar(i,:)=Xreal(ste_inter,:);
                guardar2(i,:,:)=Xreal(:,:);
               
               
            end
        
        %    guardar=app.NumberofensemblesSlider.UserData.guardar;
        %         Xreal=app.NumberofensemblesSlider.UserData.Xreal;
        
            ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
        x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


        fill(app.UIAxes,x2, inBetween, 'g');
         hold(app.UIAxes,"on");
         plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
         plot(app.UIAxes,mean(guardar),'k');
         title(app.UIAxes,sprintf('Forcing factor=%.3f state=%.0f',app.ForcingSlider.Value,app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
         xline(app.UIAxes,app.SelecttimestepSpinner.Value)
         xlim(app.UIAxes,[0 Tsim])
         ylim(app.UIAxes,[-15 15])
        
        imagesc(app.UIAxes2,guardar)
        title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        xlim(app.UIAxes2,[0 Tsim])
        ylim(app.UIAxes2,[1 num])
        colormap(app.UIAxes2,cool)
        colorbar(app.UIAxes2)
        
        imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
        title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        xlim(app.UIAxes6,[0 Tsim])
        ylim(app.UIAxes6,[1 n])
        colormap(app.UIAxes6,cool)
        colorbar(app.UIAxes6)

             end


        end

        % Value changed function: StatetovisualizeSpinner
        function StatetovisualizeSpinnerValueChanged(app, event)
             if strcmp(app.Switch.Value,'On')
            ste_inter = app.StatetovisualizeSpinner.Value;


        cla(app.UIAxes)  
        cla(app.UIAxes2)  
        
          changingValueknob = app.DisturbparameterKnob.Value;


        num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
        rng(30)
        Tsim=app.SimulationtimeEditField.Value;        % Simulation time
        dt=0.01;         % Step length
        
        n=app.NumberofstatesEditField.Value;            % State number
        m=n;            % Number of observations
        %F=8;             % Forcing factor model  In this version of the code the
        %Forcing parameter is perturbed
        r=3;             % Predecesors radio
        sigma=1e-2;        % Noise factor for the error covariance matrix terms
        R=sigma^2*eye(m);  % Observation Covariance matrix 
        H = eye(n,n);      % Observation operator matrix
        H = H(randperm(n,m),:);
        M=2; %Ventana de asimilacion
        frequency=1; % Frequency of observations  
        
        %% ==============Generate real state=================
        % This part generates the ensemble perturbing the forcing parameter 
        
        sparse=app.EnsembleInflationKnob.Value;
        
        x0=sparse*app.NumberofstatesEditField.UserData.x0;
        incr=2;
        
        guardar=zeros(num,Tsim+M*frequency);
        
        fact=changingValueknob;     
            Dist=fact*randn(num,1);
            FF=app.ForcingSlider.Value;
            for i=1:num
                FF=FF+Dist(randi([1 num]),1);
                [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
                % a=plot(Xreal(30,:),'g');
                guardar(i,:)=Xreal(ste_inter,:);
                guardar2(i,:,:)=Xreal(:,:);
              
               
            end
        
        %    guardar=app.NumberofensemblesSlider.UserData.guardar;
        %         Xreal=app.NumberofensemblesSlider.UserData.Xreal;
        
            ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
        x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


        fill(app.UIAxes,x2, inBetween, 'g');
         hold(app.UIAxes,"on");
         plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
         plot(app.UIAxes,mean(guardar),'k');
         title(app.UIAxes,sprintf('Forcing factor=%.3f state=%.0f',app.ForcingSlider.Value,app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        
         xline(app.UIAxes,app.SelecttimestepSpinner.Value)
         xlim(app.UIAxes,[0 Tsim])
         ylim(app.UIAxes,[-15 15])
        
        imagesc(app.UIAxes2,guardar)
        title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        xlim(app.UIAxes2,[0 Tsim])
        ylim(app.UIAxes2,[1 num])
        colormap(app.UIAxes2,cool)
        colorbar(app.UIAxes2)


        imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
        title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        xlim(app.UIAxes6,[0 Tsim])
        ylim(app.UIAxes6,[1 n])
        colormap(app.UIAxes6,cool)
        colorbar(app.UIAxes6)


             end

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: ForcingSlider
        function ForcingSliderValueChanged(app, event)
             if strcmp(app.Switch.Value,'On')
            value = app.ForcingSlider.Value;


        cla(app.UIAxes)  
        cla(app.UIAxes2)  
        
          changingValueknob = app.DisturbparameterKnob.Value;


        num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
        
        Tsim=app.SimulationtimeEditField.Value;        % Simulation time
        dt=0.01;         % Step length
        
        n=app.NumberofstatesEditField.Value;            % State number
        m=n;            % Number of observations
        ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
        %F=8;             % Forcing factor model  In this version of the code the
        %Forcing parameter is perturbed
        r=3;             % Predecesors radio
        sigma=1e-2;        % Noise factor for the error covariance matrix terms
        R=sigma^2*eye(m);  % Observation Covariance matrix 
        H = eye(n,n);      % Observation operator matrix
        H = H(randperm(n,m),:);
        M=2; %Ventana de asimilacion
        frequency=1; % Frequency of observations  
        
        %% ==============Generate real state=================
        % This part generates the ensemble perturbing the forcing parameter 
        
        sparse=app.EnsembleInflationKnob.Value;
        
        x0=sparse*app.NumberofstatesEditField.UserData.x0;
        incr=2;
        
        guardar=zeros(num,Tsim+M*frequency);
        
        fact=changingValueknob;     
        randVar=app.NumberofensemblesSlider.UserData.randVar;     
        Dist=fact*randVar;
        
             FF=app.ForcingSlider.Value;
             for i=1:num
                 FF=FF+Dist(randi([1 num]),1);
                 [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
                 % a=plot(Xreal(30,:),'g');
                 guardar(i,:)=Xreal(ste_inter,:);
                 guardar2(i,:,:)=Xreal(:,:);
                
                
             end
        
        %    guardar=app.NumberofensemblesSlider.UserData.guardar;
        %         Xreal=app.NumberofensemblesSlider.UserData.Xreal;
        
            ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
        x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


        fill(app.UIAxes,x2, inBetween, 'g');
         hold(app.UIAxes,"on");
         plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
         plot(app.UIAxes,mean(guardar),'k');
         title(app.UIAxes,sprintf('Forcing factor=%.3f state=%.0f',app.ForcingSlider.Value,app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        
         xline(app.UIAxes,app.SelecttimestepSpinner.Value)
         xlim(app.UIAxes,[0 Tsim])
        
        imagesc(app.UIAxes2,guardar)
        title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        
        xlim(app.UIAxes2,[0 Tsim])
        ylim(app.UIAxes2,[1 num])
        colormap(app.UIAxes2,cool)
        colorbar(app.UIAxes2)
        
        
        imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
        title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
        xlim(app.UIAxes6,[0 Tsim])
        ylim(app.UIAxes6,[1 n])
        colormap(app.UIAxes6,cool)
        colorbar(app.UIAxes6)


             end


        end

        % Callback function
        function SwitchValueChanged(app, event)
      value = app.SwitchValueChanged.Value;
    
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
            app.EnsembleInflationKnob.Value=0.01;
            app.DisturbparameterKnob.Value=0;
            app.SimulationtimeEditField.Value=0;
            app.NumberofstatesEditField.Value=0;
           
            app.ForcingSlider.Value=6;
            app.Lamp.Color = 'r'; 
            cla(app.UIAxes)  
            cla(app.UIAxes2) 
            cla(app.UIAxes3)  
            cla(app.UIAxes4) 
            cla(app.UIAxes5)
            cla(app.UIAxes6) 
            cla(app.UIAxes7) 
            cla(app.UIAxes8) 
            cla(app.UIAxes9)
            cla(app.UIAxes10) 
            cla(app.UIAxes11) 
            cla(app.UIAxes12) 
            app.RMSEGauge.Value=0;
            app.Radio.Value=1;
            app.Radio1Button.Value=1;
            else
          app.Lamp.Color = 'g';   
          app.SimulationtimeEditField.Value=0;
          app.NumberofstatesEditField.Value=0;
          app.SimulationtimeEditField.Value=0;
          app.DisturbparameterKnob.Value=0;
         
          
          app.EnsembleInflationKnob.Value=0.01;
          app.ForcingSlider.Value=6;
            end
      
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;


            if strcmp(value,'On')
            app.Lamp2.Color = 'g'; 
            lag=app.DAtechniqueDropDown.UserData.lag;
%           app.RMSEGauge.Value=calc_RMSE(V1,V2(1:end-20));
            
            c=calc_RMSE(squeeze(app.DAtechniqueDropDown.UserData.V1),squeeze(app.DAtechniqueDropDown.UserData.V2));
            corrG=corrcoef(app.DAtechniqueDropDown.UserData.V1,app.DAtechniqueDropDown.UserData.V2);
            
            FB=2*((mean(app.DAtechniqueDropDown.UserData.V1)-mean(app.DAtechniqueDropDown.UserData.V2))/(mean(app.DAtechniqueDropDown.UserData.V1)+mean(app.DAtechniqueDropDown.UserData.V2(1:end-lag))));
            
            app.MFBGauge.Value = FB;
            app.RMSEGauge.Value = c;
            app.CORRGauge.Value =corrG(1,2);
            app.RootMeanSquareEditField.Value=c;
            app.CorrelationEditField.Value=corrG(1,2);
            app.FractionalBiasEditField.Value=FB;
            else
            app.Lamp2.Color = 'r'; 
            app.MFBGauge.Value = 0;
            app.RMSEGauge.Value =0;
            app.CORRGauge.Value =0;
            app.RootMeanSquareEditField.Value=0;
            app.CorrelationEditField.Value=0;
            app.FractionalBiasEditField.Value=0;
            end
            
        end

        % Value changed function: EnsembleInflationKnob
        function EnsembleInflationKnobValueChanged(app, event)
            if strcmp(app.Switch.Value,'On')
            sparse = app.EnsembleInflationKnob.Value;

            
cla(app.UIAxes)  
cla(app.UIAxes2)  

  changingValueknob = app.DisturbparameterKnob.Value;


num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
rng(30)
Tsim=app.SimulationtimeEditField.Value;        % Simulation time
dt=0.01;         % Step length

n=app.NumberofstatesEditField.Value;            % State number
m=n;            % Number of observations
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion
frequency=1; % Frequency of observations  

%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 


x0=sparse*app.NumberofstatesEditField.UserData.x0;
incr=2;

guardar=zeros(num,Tsim+M*frequency);

fact=changingValueknob;     
  randVar=app.NumberofensemblesSlider.UserData.randVar;     
Dist=fact*randVar;
    FF=app.ForcingSlider.Value;
    for i=1:num
        FF=FF+Dist(randi([1 num]),1);
        [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector
        % a=plot(Xreal(30,:),'g');
        guardar(i,:)=Xreal(ste_inter,:);
        guardar2(i,:,:)=Xreal(:,:);
        
       
    end

%    guardar=app.NumberofensemblesSlider.UserData.guardar;
%        Xreal=app.NumberofensemblesSlider.UserData.Xreal;

    ss=std(guardar(:,:));
    y=mean(guardar);

 app.EnsembleInflationKnob.UserData.ymean=mean(guardar2);

    curve1 = y + ss;curve2 = y - ss;
x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];


fill(app.UIAxes,x2, inBetween, 'g');
 hold(app.UIAxes,"on");
 plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
 plot(app.UIAxes,mean(guardar),'k');
 title(app.UIAxes,sprintf('Forcing factor=%.3f state=%.0f',app.ForcingSlider.Value,app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

 xline(app.UIAxes,app.SelecttimestepSpinner.Value)
 xlim(app.UIAxes,[0 Tsim])

imagesc(app.UIAxes2,guardar)
title(app.UIAxes2,sprintf('State number=%.0f',app.StatetovisualizeSpinner.Value));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')

xlim(app.UIAxes2,[0 Tsim])
ylim(app.UIAxes2,[1 num])
colormap(app.UIAxes2,cool)
colorbar(app.UIAxes2)


imagesc(app.UIAxes6,squeeze(guardar2(num,:,:)))
title(app.UIAxes6,sprintf('Ensemble number=%.0f',num));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
xlim(app.UIAxes6,[0 Tsim])
ylim(app.UIAxes6,[1 n])
colormap(app.UIAxes6,cool)
colorbar(app.UIAxes6)

            end


        end

        % Value changed function: SelecttimestepSpinner
        function SelecttimestepSpinnerValueChanged(app, event)
            value = app.SelecttimestepSpinner.Value;
           
          
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Callback function: AButton, DAtechniqueDropDown, 
        % DAtechniqueDropDown
        function DAtechniqueDropDownValueChanged(app, event)
            
            value = app.DAtechniqueDropDown.Value;
            switch app.DAtechniqueDropDown.Value
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
                case 'EnKF'
 if strcmp(app.Switch.Value,'On')
             
cla(app.UIAxes5) 

changingValueknob = app.DisturbparameterKnob.Value;
num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
Tsim=app.SimulationtimeEditField.Value;                          % Simulation time
sparse=app.EnsembleInflationKnob.Value;
x0=sparse*app.NumberofstatesEditField.UserData.x0;
n=app.NumberofstatesEditField.Value;                             % State number
ste_inter=app.StatetovisualizeSpinner.Value;                     % State to focus the visualizations 

dt=0.01;         % Step length
m=n;            % Number of observations

%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=2; %Ventana de asimilacion

frequency=app.ObservationfrequencySpinner.Value; % Frequency of observations
pfactorEnkf = 1.2;  %INFLATION FACTOR
lag=frequency*M;
app.DAtechniqueDropDown.UserData.lag=lag;
%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

FF=7;
guardar=zeros(num,Tsim+M*frequency);
fact=changingValueknob;

% Dist=fact*randn(num,1);  % additive disturbing noise for the Forcing factor
%     
%    
% FF=FF+Dist(randi([1 num]),1);
% [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector of the real state

Xreal=squeeze(app.EnsembleInflationKnob.UserData.ymean);
%% ================= Data assimilation part ===========================
% 
 Xb=zeros(n,num,Tsim);       % Background ensemble initialization
% X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
% X_S=zeros(n,num,Tsim);
% 
 meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
 Xb(:,:,1)=0.5*randn(n,num);      % Initial condition creation
% 
 Xa=Xb;

 meanxa_EnKF=zeros(n,Tsim);              % initialization of the vector to put the analisis ENKF mean
 B=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for EnKF
 muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
%  
 Y=H*Xreal;     %  Sampling of the observation operator

 for i=2:Tsim-1
%======== Forecast Step==========

% foplot(movmean(Error_ENKF,window));hold on
% plot(movmean(Error_ENKF_schur,window));hold on
% plot(movmean(Error_ENKF_MC,window));hold on
% plot(movmean(Error_ENKS,window));hold on
% plot(movmean(Error_ENKS_chol,window));hold on
  for en=1:num
    % FF=7;
    %fact=0.1;
    randVar=app.NumberofensemblesSlider.UserData.randVar;     
    Dist=fact*randVar;
    FF=FF+Dist(randi([1 num]),1);
    [Xb(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(Xb(:,en,i-1)),FF);
  end
  
     meanxb(:,i)=mean(Xb(:,:,i),2);    
     
if sum(muestreo==i)   %Validation if there are a observation available
     
%   for Nen=1:N
%      XXb(:,Nen,i)= Xb(:,Nen,i)-meanxb(:,i);  
%   end
%   
      Xbp(:,:,i)=meanxb(:,i)*ones(1,num)+ pfactorEnkf*(Xb(:,:,i)-meanxb(:,i)*ones(1,num));
      meanXbp(:,i) = mean(Xbp(:,:,i),2);   
      XXb(:,:,i) =Xbp(:,:,i)-meanXbp(:,i)*ones(1,num);
% Standard EnKF Background matrix 

   Binv=pinv((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));
   BCov(i,:,:)=Binv;

  % [L,D, Dsquare]=Calculo_B_CholeskyInc(XXb,r);
  % Binv = L'*D*L;
  
   %%
%======================== Analysis Step ENKF=========================================
muestra=0;     
%K=B*H'*pinv(H*B*H'+R);     % Matriz de Ganancia de Kalman
 
  K2 = (Binv+ H'*(R\H));
    for en=1:num
         
         C(:,i)= Y(:,i)+sigma*randn(m,1); %  Almacenamiento de los datos sint??ticos
          D= (C(:,i)-H*Xb(:,en,i));   
         Xa(:,en,i)=Xb(:,en,i)+((K2\H')*(R\D));

    end
    Xb(:,:,i)=Xa(:,:,i);
    
    
else
     Xb(:,:,i)=Xb(:,:,i);
end
meanxa_EnKF(:,i)=mean(Xb(:,:,i),2);

end


plot(app.UIAxes5,Xreal(ste_inter,:),'r','LineWidth',2)
hold(app.UIAxes5,'On')
plot(app.UIAxes5,meanxa_EnKF(ste_inter,:),'--b','LineWidth',2)
legend(app.UIAxes5,{'X truth','Xa ENKF'},'Location', 'Best')
xlim(app.UIAxes5,[0 Tsim])
ylim(app.UIAxes5,[-15 15])
% hold on

 imagesc(app.UIAxes4,squeeze(BCov(app.SelecttimestepSpinner.Value,:,:)));
 colormap(app.UIAxes4,'jet'); colorbar(app.UIAxes4)
xlim(app.UIAxes4,[1 n])
ylim(app.UIAxes4,[1 n])

for i=1:Tsim
Error_ENKF(i)=norm(abs(sum(meanxa_EnKF(ste_inter,i)-Xreal(ste_inter,i))));
end
V1=squeeze(meanxa_EnKF(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));

app.DAtechniqueDropDown.UserData.V1=V1(1:Tsim);
app.DAtechniqueDropDown.UserData.V2=V2(1:Tsim);

plot(app.UIAxes3,movmean(Error_ENKF,10));
legend(app.UIAxes3,{'EnKF'})

%     ss=std(guardar(:,:));y=mean(guardar);curve1 = y + ss;curve2 = y - ss;
% x = 1:numel(y);x2 = [x, fliplr(x)];inBetween = [curve1, fliplr(curve2)];
% 
% 
% 
% fill(app.UIAxes,x2, inBetween, 'g');
%  hold(app.UIAxes,"on");
%  plot(app.UIAxes,x, y, 'r', 'LineWidth', 2); hold(app.UIAxes,"on");
%  plot(app.UIAxes,mean(guardar),'k');
%  title(app.UIAxes,sprintf('Forcing factor=%.3f',fact));xlabel(app.UIAxes,'timestep');ylabel(app.UIAxes,'State magnitude')
%  grid on
%  xline(app.UIAxes,app.SelecttimestepSpinner.Value)
%  xlim(app.UIAxes,[0 Tsim])
% 
% imagesc(app.UIAxes2,guardar)
% xlim(app.UIAxes2,[0 Tsim])
% ylim(app.UIAxes2,[0 num])
% colormap(app.UIAxes2,cool)
% colorbar(app.UIAxes2)


 end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKF Schur'
     if strcmp(app.Switch.Value,'On')
            
            cla(app.UIAxes7)  
            
            changingValueknob = app.DisturbparameterKnob.Value;
            num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
            Tsim=app.SimulationtimeEditField.Value;                          % Simulation time
            sparse=app.EnsembleInflationKnob.Value;
            x0=sparse*app.NumberofstatesEditField.UserData.x0;
            n=app.NumberofstatesEditField.Value;                             % State number
            ste_inter=app.StatetovisualizeSpinner.Value;                     % State to focus the visualizations 
            
            dt=0.01;         % Step length
            m=n;             % Number of observations
  
            %F=8;             % Forcing factor model  In this version of the code the
            %Forcing parameter is perturbed
            radi=app.Radio.Value;             % Localization radio
            sigma=1e-2;                       % Noise factor for the error covariance matrix terms
            R=sigma^2*eye(m);                 % Observation Covariance matrix 
            H = eye(n,n);                     % Observation operator matrix
            H = H(randperm(n,m),:);
            M=2;                              % Ventana de asimilacion

            frequency=app.ObservationfrequencySpinner.Value; % Frequency of observations
            pfactorEnkFS = 1.2;                              % INFLATION FACTOR
            
            lag=frequency*M;
            app.DAtechniqueDropDown.UserData.lag=lag;
            %% ==============Generate real state=================
            % This part generates the ensemble perturbing the forcing parameter 
            
            FF=7;
            guardar=zeros(num,Tsim+M*frequency);
            fact=changingValueknob;

       
            % Dist=fact*randn(num,1);  % additive disturbing noise for the Forcing factor    
            % FF=FF+Dist(randi([1 num]),1);
            % [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector of the real state
            
            Xreal=squeeze(app.EnsembleInflationKnob.UserData.ymean);
            %% ================= Data assimilation part ===========================
            % 
             X_schur=zeros(n,num,Tsim);       % Background ensemble initialization
            % X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
            % X_S=zeros(n,num,Tsim);
            % 
             meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
             X_schur(:,:,1)=0.5*randn(n,num);      % Initial condition creation
            % 
             Xa=X_schur;
             meanxa_EnKF_schur=zeros(n,Tsim);              % initialization of the vector to put the analisis ENKF mean
             B=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for EnKF
             muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
            %  
             Y=H*Xreal;     %  Sampling of the observation operator             
             for i=2:Tsim-1
            % %======== Forecast Step==========
            %   for en=1:num
            %       [X_schur(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(X_schur(:,en,i-1)),F);
            %   end
             
               for en=1:num
                  % FF=7;
                  Dist=fact*randn(num,1);
                  FF=FF+Dist(randi([1 num]),1);
                 [X_schur(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(X_schur(:,en,i-1)),FF);
               end
               
              
                 meanxb(:,i)=mean(X_schur(:,:,i),2);    
                 
            if sum(muestreo==i)   %Validation if there are a observation available
            %      
            %    for Nen=1:N
            %       XXb(:,Nen,i)= X_schur(:,Nen,i)-meanxb(:,i);  
            %    end
            %   i
              Xbp(:,:,i)=meanxb(:,i)*ones(1,num)+ pfactorEnkFS*(X_schur(:,:,i)-meanxb(:,i)*ones(1,num));
              meanXbp(:,i) = mean(Xbp(:,:,i),2);   
              XXb(:,:,i) =Xbp(:,:,i)-meanXbp(:,i)*ones(1,num);
            
            % %  Standard EnKF Background matrix 
            % 
               B=((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));
               L=localization_matrix(n,radi); 
               BBB=L.*B;
               %BBB=B;
               BCov(i,:,:)=pinv(BBB); 
            % %======================== Analysis Step ENKF=========================================
            % muestra=0;     
            %K=B*H'*pinv(H*B*H'+R);     % Matriz de Ganancia de Kalman
             
              K2 = (B+ H'*(R\H));
                 for en=1:num
            %          
                      C(:,i)= Y(:,i)+sigma*randn(m,1);        %  Almacenamiento de los datos sint??ticos
                      D= (C(:,i)-H*X_schur(:,en,i)); 
                      Xa(:,en,i)=X_schur(:,en,i)+(K2\H')*(R\D);
             
                 end
                 X_schur(:,:,i)=Xa(:,:,i);
               
            else
                 X_schur(:,:,i)=X_schur(:,:,i);
             end
            
               meanxa_EnKF_schur(:,i)=mean(X_schur(:,:,i),2); 
             end
            % % 
            

plot(app.UIAxes7,Xreal(ste_inter,:),'r','LineWidth',2)
hold(app.UIAxes7,'On')
plot(app.UIAxes7,meanxa_EnKF_schur(ste_inter,:),'--b','LineWidth',2)
legend(app.UIAxes7,{'X truth','Xa ENKF Schur '},'Location','best')
xlim(app.UIAxes7,[0 Tsim])
ylim(app.UIAxes7,[-15 15])
% hold on

%  imagesc(app.UIAxes4,squeeze(BCov(:,:,app.SelecttimestepSpinner.Value)));
%  colormap(app.UIAxes4,'jet'); colorbar(app.UIAxes4)
% xlim(app.UIAxes4,[1 n])
% ylim(app.UIAxes4,[1 n])

for i=1:Tsim
Error_ENKF_schur(i)=norm(abs(sum(meanxa_EnKF_schur(ste_inter,i)-Xreal(ste_inter,i))));
end
V1=squeeze(meanxa_EnKF_schur(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));


app.DAtechniqueDropDown.UserData.V1=V1(1:Tsim);
app.DAtechniqueDropDown.UserData.V2=V2(1:Tsim);

plot(app.UIAxes3,movmean(Error_ENKF_schur,10));
legend(app.UIAxes3,{'EnKF Schur'})


 imagesc(app.UIAxes8,squeeze(BCov(app.SelecttimestepSpinner.Value,:,:)));
 colormap(app.UIAxes8,'jet'); colorbar(app.UIAxes8)
xlim(app.UIAxes8,[1 n])
ylim(app.UIAxes8,[1 n])


     end

            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKS'
             
             if strcmp(app.Switch.Value,'On')
            
            
           cla(app.UIAxes11) 


changingValueknob = app.DisturbparameterKnob.Value;

num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble

Tsim=app.SimulationtimeEditField.Value;        % Simulation time

sparse=app.EnsembleInflationKnob.Value;

x0=sparse*app.NumberofstatesEditField.UserData.x0;

n=app.NumberofstatesEditField.Value;            % State number
ste_inter=app.StatetovisualizeSpinner.Value;    % State to focus the visualizations 

dt=0.01;         % Step length
m=n;            % Number of observations

%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;             % Predecesors radio
sigma=1e-2;        % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);  % Observation Covariance matrix 
H = eye(n,n);      % Observation operator matrix
H = H(randperm(n,m),:);
M=3; %Ventana de asimilacion

frequency=app.ObservationfrequencySpinner.Value; % Frequency of observations
pfactorEnkf = 1.2;  %INFLATION FACTOR

lag=frequency*M;
app.DAtechniqueDropDown.UserData.lag=lag;
%% ==============Generate real state=================
% This part generates the ensemble perturbing the forcing parameter 

FF=7;
guardar=zeros(num,Tsim+M*frequency);
fact=changingValueknob;

       
% randVar=app.NumberofensemblesSlider.UserData.randVar;     
% Dist=fact*randVar;   
%    
% FF=FF+Dist(randi([1 num]),1);
% [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector of the real state


Xreal=squeeze(app.EnsembleInflationKnob.UserData.ymean);


%% ================= Data assimilation part ===========================
% 
 Xb=zeros(n,num,Tsim);       % Background ensemble initialization
% X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
X_S=zeros(n,num,Tsim);
% 
 meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
 X_S(:,:,1)=0.5*randn(n,num);      % Initial condition creation
% 
 Xa=Xb;

 meanxa_EnKF=zeros(n,Tsim);              % initialization of the vector to put the analisis ENKF mean
 B=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for EnKF

 muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
%  
 Y=H*Xreal;     %  Sampling of the observation operator


%  for i=2:Tsim-M*frequency
    for i=2:Tsim
 %======== Forecast Step==========
 
    for en=1:num
      
      Dist=fact*randn(num,1);
      FF=FF+Dist(randi([1 num]),1); 
        [X_S(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(X_S(:,en,i-1)),FF);
    end
  
     meanxb(:,i)=mean(X_S(:,:,i),2);    
    
  for Nen=1:num
     XXb(:,Nen,i)= X_S(:,Nen,i)-meanxb(:,i);  
  end
  
%  Standard EnKF Background matrix 

   B(:,:,i)=((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));

%    Binv=pinv((1/(num-1))*(XXb(:,:,i)*XXb(:,:,i)'));
%     BCov(i,:,:)=Binv;


   if sum(muestreo==i)   %Validation if there are a observation available
%======================== Analysis Step ENKS=========================================

    X_aw(:,:,1)=X_S(:,:,i);
    
    if M==1
    B_aw(:,:,1)=B(:,:,i);    
    end
        for kk=2:(M*frequency)
            for en=1:num    
                [X_aw(:,en,kk)]=Lorenz_96_one_step(1,dt,squeeze(X_aw(:,en,kk-1)),FF);
            end
            meanxb_aw(:,kk)=mean(X_aw(:,:,kk),2);    
    
             for Nen=1:num
                XXb_aw(:,Nen,kk)= X_aw(:,Nen,kk)-meanxb_aw(:,kk)+1*randn(n,1);  
             end
  
             B_aw(:,:,kk)=((1/(num-1))*(XXb_aw(:,:,kk)*XXb_aw(:,:,kk)'));   
        end
    aux=0;

    for kk2=1:frequency:M*frequency
        K(:,:,kk2)=B_aw(:,:,kk2)*H'*pinv(H*B_aw(:,:,kk2)*H'+R);     % Matriz de Ganancia de Kalman
        C(:,kk2)= Y(:,i+kk2-1)+sigma*randn(m,1);
        aux=aux+(K(:,:,kk2)*(C(:,kk2)*ones(1,num)-H*X_aw(:,:,kk2)))/M;
    end
    
    for en=1:num
      Xa(:,en,i)=X_S(:,en,i)+aux(:,en);
    end
    

else 
  Xa(:,:,i)=X_S(:,:,i);  
end
  meanxa_EnKS(:,i)=mean(Xa(:,:,i),2);                                                                                                                                                                                                                                                                                                                                              
end


plot(app.UIAxes11,Xreal(ste_inter,:),'r','LineWidth',2)
hold(app.UIAxes11,'On')
plot(app.UIAxes11,meanxa_EnKS(ste_inter,:),'--b','LineWidth',2)
legend(app.UIAxes11,{'X truth','Xa ENKS'},'Location','best')
xlim(app.UIAxes11,[0 Tsim])
ylim(app.UIAxes11,[-15 15])
% hold on

%  imagesc(app.UIAxes4,squeeze(BCov(:,:,app.SelecttimestepSpinner.Value)));
%  colormap(app.UIAxes4,'jet'); colorbar(app.UIAxes4)
% xlim(app.UIAxes4,[1 n])
% ylim(app.UIAxes4,[1 n])

for i=1:Tsim
Error_ENKS(i)=norm(abs(sum(meanxa_EnKS(ste_inter,i)-Xreal(ste_inter,i))));
end
V1=squeeze(meanxa_EnKS(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));

app.DAtechniqueDropDown.UserData.V1=V1(1:Tsim);
app.DAtechniqueDropDown.UserData.V2=V2(1:Tsim);

plot(app.UIAxes3,movmean(Error_ENKS,10));
legend(app.UIAxes3,{'EnKS'})


 imagesc(app.UIAxes12,squeeze(B(:,:,app.SelecttimestepSpinner.Value)));
 colormap(app.UIAxes12,'jet'); colorbar(app.UIAxes12)
xlim(app.UIAxes12,[1 n])
ylim(app.UIAxes12,[1 n])


             end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            case 'EnKS MC'
             
           
            cla(app.UIAxes3)  
            cla(app.UIAxes14) 
            cla(app.UIAxes13)  
  
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            

             case 'EnKF MC'
 if strcmp(app.Switch.Value,'On')

            
            cla(app.UIAxes9) 
           

changingValueknob = app.DisturbparameterKnob.Value;              % Disturb parameter
num=app.SimulationtimeEditField.UserData.changingValue ;         % Num ensemble
Tsim=app.SimulationtimeEditField.Value;                          % Simulation time
sparse=app.EnsembleInflationKnob.Value;                          % Inflation
x0=sparse*app.NumberofstatesEditField.UserData.x0;               % State number
n=app.NumberofstatesEditField.Value;                             % State number
ste_inter=app.StatetovisualizeSpinner.Value;                     % State to focus the visualizations 

dt=0.01;                                                         % Step length
m=n;                                                             % Number of observations
%F=8;             % Forcing factor model  In this version of the code the
%Forcing parameter is perturbed
r=3;                                                             % Predecesors radio
sigma=1e-2;                                                      % Noise factor for the error covariance matrix terms
R=sigma^2*eye(m);                                                % Observation Covariance matrix 
H = eye(n,n);                                                    % Observation operator matrix
H = H(randperm(n,m),:);
M=2;                                                             %  Ventana de asimilacion

frequency=app.ObservationfrequencySpinner.Value;                 % Frequency of observations
pfactorEnkfMC = 1.2;                                               % INFLATION FACTOR
lag=frequency*M;
app.DAtechniqueDropDown.UserData.lag=lag;
%% ==============Generate real state=================

X_chol=zeros(n,num,Tsim);
% 
             Xb=zeros(n,num,Tsim);       % Background ensemble initialization
            % X_chol=zeros(n,num,Tsim);       % Background ensemble initialization
            % X_S=zeros(n,num,Tsim);
            % 
             meanxb=zeros(n,Tsim);       % Background ensemble mean initialization
             X_chol(:,:,1)=0.5*randn(n,num);      % Initial condition creation
            % 
             Xa=X_chol;
            
              meanxa_EnKF_CHOLESKY=zeros(n,Tsim);              % initialization of the vector to put the analisis ENKF mean
             B=zeros(n,n,Tsim);            % initialization of the covariance background error matrix for EnKF
            
             muestreo=frequency:frequency:Tsim;     % vector to put the observation to assimilate
            %  


% This part generates the ensemble perturbing the forcing parameter 
FF=7;
guardar=zeros(num,Tsim+M*frequency);
fact=changingValueknob;
% Dist=fact*randn(num,1);  % additive disturbing noise for the Forcing factor
%    
% FF=FF+Dist(randi([1 num]),1);
% [Xreal]=Lorenz_96(Tsim+M*frequency,dt,x0,FF);            % Create vector of the real state

Xreal=squeeze(app.EnsembleInflationKnob.UserData.ymean);

 Y=H*Xreal;     %  Sampling of the observation operator
%if select==2
  
for i=2:Tsim-1
%================= Forecast Step==========
   for en=1:num
       Dist=fact*randn(num,1);
                  FF=FF+Dist(randi([1 num]),1);
     [X_chol(:,en,i)]=Lorenz_96_one_step(1,dt,squeeze(X_chol(:,en,i-1)),FF);
   end
   

     meanxb(:,i)=mean(X_chol(:,:,i),2);    

%      
%    for Nen=1:N
%       XXb(:,Nen,i)= X_chol(:,Nen,i)-meanxb(:,i);  
%    end
  Xbp(:,:,i)=meanxb(:,i)*ones(1,num)+ pfactorEnkfMC*(X_chol(:,:,i)-meanxb(:,i)*ones(1,num));
  meanXbp(:,i) = mean(Xbp(:,:,i),2);   
  XXb(:,:,i) =Xbp(:,:,i)-meanXbp(:,i)*ones(1,num);
%   i
%    %XXa=XXb(:,:,i)*sqrt(1/(N-1));   
%     BC(:,:,i)=Calculo_B_Cholesky(XXb,r); %Estimation of Covariance by Modified Cholesky
    inB(i,:,:)=Calculo_inB_Cholesky(XXb,r); %Estimation of invserse Covariance by Modified Cholesky
% %  Bsquare=B^(1/2);
% %  L=Xb(:,:,i)-meanxb;
% %     
% %%  Standard EnKF MC Background matrix 
% 
%   
%      
% %=============== Analysis Step ENKF MC========================================     
if sum(muestreo==i)   %Validation if there are a observation available    
% KC=BC(:,:,i)*H'*pinv(H*BC(:,:,i)*H'+R);     % Matriz de Ganancia de Kalman para cholesky

 K2C = (squeeze(inB(i,:,:))+ H'*(R\H));     
%      
      for en=1:num
%          
          C(:,i)= Y(:,i)+sigma*randn(m,1);        %  Almacenamiento de los datos sint??ticos
%           Xa_C(:,en,i)=X_chol(:,en,i)+KC*(C(:,i)-H*X_chol(:,en,i));
          D= (C(:,i)-H*X_chol(:,en,i)); 
          Xa_C(:,en,i)= X_chol(:,en,i) + (K2C\H')*(R\D);
% 
      end
%      
      X_chol(:,:,i)=Xa_C(:,:,i);
    
 
%      

else
     X_chol(:,:,i)=X_chol(:,:,i);
end                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                     
 meanxa_EnKF_CHOLESKY(:,i)=mean(X_chol(:,:,i),2);

end


plot(app.UIAxes9,Xreal(ste_inter,:),'r','LineWidth',2)
hold(app.UIAxes9,'On')
plot(app.UIAxes9,meanxa_EnKF_CHOLESKY(ste_inter,:),'--b','LineWidth',2)
legend(app.UIAxes9,{'X truth','Xa ENKF'},'Location', 'Best')
xlim(app.UIAxes9,[0 Tsim])
ylim(app.UIAxes9,[-15 15])
% hold on

 imagesc(app.UIAxes10,squeeze(inB(app.SelecttimestepSpinner.Value,:,:)));
 colormap(app.UIAxes10,'jet'); colorbar(app.UIAxes10)
xlim(app.UIAxes10,[1 n])
ylim(app.UIAxes10,[1 n])

for i=1:Tsim
Error_ENKF_CHOLESKY(i)=norm(abs(sum(meanxa_EnKF_CHOLESKY(ste_inter,i)-Xreal(ste_inter,i))));
end
V1=squeeze(meanxa_EnKF_CHOLESKY(ste_inter,:));
V2=squeeze(Xreal(ste_inter,:));

app.DAtechniqueDropDown.UserData.V1=V1(1:Tsim);
app.DAtechniqueDropDown.UserData.V2=V2(1:Tsim);

plot(app.UIAxes3,movmean(Error_ENKF_CHOLESKY,10));
legend(app.UIAxes3,{'EnKF Cholesky'})


 end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            end


        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)
            
        end

        % Size changed function: ScoresPanel
        function ScoresPanelSizeChanged(app, event)
            position = app.ScoresPanel.Position;
            
        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Value changed function: ObservationfrequencySpinner
        function ObservationfrequencySpinnerValueChanged(app, event)
            value = app.ObservationfrequencySpinner.Value;
            
        end

        % Button down function: UIAxes11
        function UIAxes11ButtonDown(app, event)
            
        end

        % Selection changed function: RadiusLocalizationButtonGroup
        function RadiusLocalizationButtonGroupSelectionChanged(app, event)
            %selectedButton = app.RadiusLocalizationButtonGroup.SelectedObject;
            
            if app.Radio1Button.Value==1   %where "Button1" is the name of the first radio button
                  app.Radio.Value=1;
               
     
            elseif app.Radio2Button.Value==1
                   app.Radio.Value=2;   
              
    
             elseif app.Radio3Button.Value==1
                   app.Radio.Value=3;
        
             end
          %  L = localization_matrix(app.NumberofstatesEditField.Value,Val);
        % app.REditField.UserData.Val=Val;
         %imagesc(app.UIAxes8,L);

        end

        % Button down function: UIAxes10
        function UIAxes10ButtonDown(app, event)
            
        end

        % Callback function
        function REditFieldValueChanged(app, event)
            
            
           % value = app.REditField.Value;
            
            
        end

        % Value changed function: CorrelationEditField
        function CorrelationEditFieldValueChanged(app, event)
            value = app.CorrelationEditField.Value;
            
        end

        % Size changed function: DAconfigurationsPanel
        function DAconfigurationsPanelSizeChanged(app, event)
            position = app.DAconfigurationsPanel.Position;
            
        end

        % Size changed function: EnKFSchurTab
        function EnKFSchurTabSizeChanged(app, event)
            position = app.EnKFSchurTab.Position;
            
        end

        % Button down function: UIAxes7
        function UIAxes7ButtonDown(app, event)
            
        end

        % Button down function: UIAxes8
        function UIAxes8ButtonDown(app, event)
            
        end

        % Value changed function: Radio
        function RadioValueChanged(app, event)
             app.Radio.Value=1;
            
        end

        % Size changed function: Panel_2
        function Panel_2SizeChanged(app, event)
            position = app.Panel_2.Position;
            
        end

        % Button down function: UIAxes12
        function UIAxes12ButtonDown(app, event)
            
        end

        % Callback function
        function AButtonPushed(app, event)
            
        end

        % Button down function: UIAxes9
        function UIAxes9ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {788, 788};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {262, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DAconfigurationsPanel = uipanel(app.LeftPanel);
            app.DAconfigurationsPanel.Title = 'DA configurations';
            app.DAconfigurationsPanel.SizeChangedFcn = createCallbackFcn(app, @DAconfigurationsPanelSizeChanged, true);
            app.EnsembleInflationKnobLabel = uilabel(app.DAconfigurationsPanel);
            app.EnsembleInflationKnobLabel.Text = 'Ensemble Inflation';
            app.EnsembleInflationKnob = uiknob(app.DAconfigurationsPanel, 'continuous');
            app.EnsembleInflationKnob.Limits = [0.01 5];
            app.EnsembleInflationKnob.ValueChangedFcn = createCallbackFcn(app, @EnsembleInflationKnobValueChanged, true);
            app.EnsembleInflationKnob.Value = 0.01;
            app.DisturbparameterKnobLabel = uilabel(app.DAconfigurationsPanel);
            app.DisturbparameterKnobLabel.Text = 'Disturb parameter ';
            app.DisturbparameterKnob = uiknob(app.DAconfigurationsPanel, 'continuous');
            app.DisturbparameterKnob.Limits = [0 2];
            app.DisturbparameterKnob.ValueChangedFcn = createCallbackFcn(app, @DisturbparameterKnobValueChanged, true);
            app.DAtechniqueDropDownLabel = uilabel(app.DAconfigurationsPanel);
            app.DAtechniqueDropDownLabel.Text = 'DA technique';
            app.DAtechniqueDropDown = uidropdown(app.DAconfigurationsPanel);
            app.DAtechniqueDropDown.Items = {'None', 'EnKF', 'EnKF Schur', 'EnKF MC', 'EnKS', 'EnKS MC'};
            app.DAtechniqueDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DAtechniqueDropDownValueChanged, true);
            app.DAtechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @DAtechniqueDropDownValueChanged, true);
            app.DAtechniqueDropDown.Value = 'None';
            app.SelecttimestepSpinnerLabel = uilabel(app.DAconfigurationsPanel);
            app.SelecttimestepSpinnerLabel.Text = 'Select timestep';
            app.SelecttimestepSpinner = uispinner(app.DAconfigurationsPanel);
            app.SelecttimestepSpinner.Limits = [1 Inf];
            app.SelecttimestepSpinner.ValueChangedFcn = createCallbackFcn(app, @SelecttimestepSpinnerValueChanged, true);
            app.SelecttimestepSpinner.Value = 1;
            app.ObservationfrequencySpinnerLabel = uilabel(app.DAconfigurationsPanel);
            app.ObservationfrequencySpinnerLabel.Text = 'Observation frequency';
            app.ObservationfrequencySpinner = uispinner(app.DAconfigurationsPanel);
            app.ObservationfrequencySpinner.Limits = [1 Inf];
            app.ObservationfrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @ObservationfrequencySpinnerValueChanged, true);
            app.ObservationfrequencySpinner.Value = 1;
            app.Image4 = uiimage(app.DAconfigurationsPanel);
            app.Image4.ImageSource = 'Forcing.svg';
            app.AButton = uibutton(app.DAconfigurationsPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @DAtechniqueDropDownValueChanged, true);
            app.AButton.Text = 'A';
            app.Panel_2 = uipanel(app.LeftPanel);
            app.Panel_2.SizeChangedFcn = createCallbackFcn(app, @Panel_2SizeChanged, true);
            app.Switch = uiswitch(app.Panel_2, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Lamp = uilamp(app.Panel_2);
            app.DataassimilationinteractiveplatformPanel = uipanel(app.LeftPanel);
            app.DataassimilationinteractiveplatformPanel.Title = 'Data assimilation interactive platform';
            app.Image2 = uiimage(app.DataassimilationinteractiveplatformPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'Lorenz96.svg';
            app.StatetovisualizeSpinnerLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.StatetovisualizeSpinnerLabel.Text = 'State to visualize';
            app.StatetovisualizeSpinner = uispinner(app.DataassimilationinteractiveplatformPanel);
            app.StatetovisualizeSpinner.Limits = [1 Inf];
            app.StatetovisualizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StatetovisualizeSpinnerValueChanged, true);
            app.StatetovisualizeSpinner.Value = 1;
            app.NumberofstatesEditFieldLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.NumberofstatesEditFieldLabel.Text = 'Number of states';
            app.NumberofstatesEditField = uieditfield(app.DataassimilationinteractiveplatformPanel, 'numeric');
            app.NumberofstatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstatesEditFieldValueChanged2, true);
            app.Lorenz96Label = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.Lorenz96Label.Text = 'Lorenz 96  ';
            app.NumberofensemblesSliderLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.NumberofensemblesSliderLabel.Text = 'Number of ensembles';
            app.NumberofensemblesSlider = uislider(app.DataassimilationinteractiveplatformPanel);
            app.NumberofensemblesSlider.Limits = [3 150];
            app.NumberofensemblesSlider.MajorTicks = [3 10 30 50 70 90 110 130 150];
            app.NumberofensemblesSlider.MajorTickLabels = {'3', '10', '30', '50', '70', '90', '110', '130', '150'};
            app.NumberofensemblesSlider.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.NumberofensemblesSlider.ValueChangingFcn = createCallbackFcn(app, @NumberofensemblesSliderValueChanging, true);
            app.NumberofensemblesSlider.MinorTicks = [];
            app.NumberofensemblesSlider.Value = 3;
            app.ForcingSliderLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.ForcingSliderLabel.Text = 'Forcing';
            app.ForcingSlider = uislider(app.DataassimilationinteractiveplatformPanel);
            app.ForcingSlider.Limits = [6 12];
            app.ForcingSlider.ValueChangedFcn = createCallbackFcn(app, @ForcingSliderValueChanged, true);
            app.ForcingSlider.Value = 6;
            app.SimulationtimeEditFieldLabel = uilabel(app.DataassimilationinteractiveplatformPanel);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.DataassimilationinteractiveplatformPanel, 'numeric');
            app.SimulationtimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimeEditFieldValueChanged, true);
            app.Image5 = uiimage(app.DataassimilationinteractiveplatformPanel);
            app.Image5.ImageSource = 'Disturb.svg';
            app.RadiusLocalizationButtonGroup = uibuttongroup(app.LeftPanel);
            app.RadiusLocalizationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadiusLocalizationButtonGroupSelectionChanged, true);
            app.RadiusLocalizationButtonGroup.Title = 'Radius Localization';
            app.Radio1Button = uiradiobutton(app.RadiusLocalizationButtonGroup);
            app.Radio1Button.Text = 'Radio 1';
            app.Radio1Button.Value = true;
            app.Radio2Button = uiradiobutton(app.RadiusLocalizationButtonGroup);
            app.Radio2Button.Text = 'Radio 2';
            app.Radio3Button = uiradiobutton(app.RadiusLocalizationButtonGroup);
            app.Radio3Button.Text = 'Radio 3';
            app.RadioLabel = uilabel(app.RadiusLocalizationButtonGroup);
            app.RadioLabel.Text = 'Radio';
            app.Radio = uieditfield(app.RadiusLocalizationButtonGroup, 'numeric');
            app.Radio.ValueChangedFcn = createCallbackFcn(app, @RadioValueChanged, true);
            app.Radio.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.AllstatesintimeLabel = uilabel(app.RightPanel);
            app.AllstatesintimeLabel.Text = 'All states in time';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'UniversitiesLogo.png';
            app.ScoresPanel = uipanel(app.RightPanel);
            app.ScoresPanel.Title = 'Scores';
            app.ScoresPanel.SizeChangedFcn = createCallbackFcn(app, @ScoresPanelSizeChanged, true);
            app.CORRGaugeLabel = uilabel(app.ScoresPanel);
            app.CORRGaugeLabel.Text = 'CORR';
            app.CORRGauge = uigauge(app.ScoresPanel, 'linear');
            app.CORRGauge.Limits = [-1 1];
            app.RMSEGaugeLabel = uilabel(app.ScoresPanel);
            app.RMSEGaugeLabel.Text = 'RMSE';
            app.RMSEGauge = uigauge(app.ScoresPanel, 'semicircular');
            app.RMSEGauge.Limits = [0 4];
            app.MFBGaugeLabel = uilabel(app.ScoresPanel);
            app.MFBGaugeLabel.Text = 'MFB';
            app.MFBGauge = uigauge(app.ScoresPanel, 'circular');
            app.MFBGauge.Limits = [-2 2];
            app.Switch_2 = uiswitch(app.ScoresPanel, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Lamp2 = uilamp(app.ScoresPanel);
            app.CorrelationEditFieldLabel = uilabel(app.ScoresPanel);
            app.CorrelationEditFieldLabel.Text = 'Correlation';
            app.CorrelationEditField = uieditfield(app.ScoresPanel, 'numeric');
            app.CorrelationEditField.ValueChangedFcn = createCallbackFcn(app, @CorrelationEditFieldValueChanged, true);
            app.RootMeanSquareEditFieldLabel = uilabel(app.ScoresPanel);
            app.RootMeanSquareEditFieldLabel.Text = 'Root Mean Square';
            app.RootMeanSquareEditField = uieditfield(app.ScoresPanel, 'numeric');
            app.FractionalBiasEditFieldLabel = uilabel(app.ScoresPanel);
            app.FractionalBiasEditFieldLabel.Text = {'Fractional Bias'; ''};
            app.FractionalBiasEditField = uieditfield(app.ScoresPanel, 'numeric');
            app.Panel_3 = uipanel(app.RightPanel);
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 'Simulation time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes2, 'Simulation time')
            ylabel(app.UIAxes2, 'Ensemble member')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes6 = uiaxes(app.Panel_3);
            xlabel(app.UIAxes6, 'Simulation time')
            ylabel(app.UIAxes6, 'State')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.TruthstateLabel = uilabel(app.Panel_3);
            app.TruthstateLabel.Text = 'Truth state';
            app.Panel_4 = uipanel(app.RightPanel);
            app.TabGroup = uitabgroup(app.Panel_4);
            app.EnKFTab = uitab(app.TabGroup);
            app.EnKFTab.Title = 'EnKF';
            app.UIAxes5 = uiaxes(app.EnKFTab);
            xlabel(app.UIAxes5, 'Simulation time')
            ylabel(app.UIAxes5, 'State')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.UIAxes4 = uiaxes(app.EnKFTab);
            title(app.UIAxes4, 'Covariance Background Matrix')
            xlabel(app.UIAxes4, 'State')
            ylabel(app.UIAxes4, 'State')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.EnKFSchurTab = uitab(app.TabGroup);
            app.EnKFSchurTab.SizeChangedFcn = createCallbackFcn(app, @EnKFSchurTabSizeChanged, true);
            app.EnKFSchurTab.Title = 'EnKF Schur';
            app.UIAxes7 = uiaxes(app.EnKFSchurTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.ButtonDownFcn = createCallbackFcn(app, @UIAxes7ButtonDown, true);
            app.UIAxes8 = uiaxes(app.EnKFSchurTab);
            title(app.UIAxes8, 'Covariance Background Matrix')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.ButtonDownFcn = createCallbackFcn(app, @UIAxes8ButtonDown, true);
            app.EnKFMCTab_2 = uitab(app.TabGroup);
            app.EnKFMCTab_2.Title = 'EnKF MC';
            app.UIAxes9 = uiaxes(app.EnKFMCTab_2);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9.ButtonDownFcn = createCallbackFcn(app, @UIAxes9ButtonDown, true);
            app.UIAxes10 = uiaxes(app.EnKFMCTab_2);
            title(app.UIAxes10, 'Covariance Background Matrix')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes10.ButtonDownFcn = createCallbackFcn(app, @UIAxes10ButtonDown, true);
            app.EnKSTab = uitab(app.TabGroup);
            app.EnKSTab.Title = 'EnKS';
            app.UIAxes11 = uiaxes(app.EnKSTab);
            xlabel(app.UIAxes11, 'Simulation time')
            ylabel(app.UIAxes11, 'State')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.ButtonDownFcn = createCallbackFcn(app, @UIAxes11ButtonDown, true);
            app.UIAxes12 = uiaxes(app.EnKSTab);
            title(app.UIAxes12, 'Covariance Background Matrix')
            xlabel(app.UIAxes12, 'state')
            ylabel(app.UIAxes12, 'state')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes12.ButtonDownFcn = createCallbackFcn(app, @UIAxes12ButtonDown, true);
            app.EnKSMCTab = uitab(app.TabGroup);
            app.EnKSMCTab.Title = 'EnKS MC';
            app.UIAxes13 = uiaxes(app.EnKSMCTab);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes14 = uiaxes(app.EnKSMCTab);
            title(app.UIAxes14, 'Covariance Background Matrix')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.Panel_5 = uipanel(app.RightPanel);
            app.UIAxes3 = uiaxes(app.Panel_5);
            xlabel(app.UIAxes3, 'Simulation time')
            ylabel(app.UIAxes3, 'Error')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YScale = 'log';
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.TimeserieserrorLabel = uilabel(app.Panel_5);
            app.TimeserieserrorLabel.Text = 'Time series error ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DALo96_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end